classdef SignalProcessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        SignalProcessorLabel  matlab.ui.control.Label
        ImportFileButton      matlab.ui.control.Button
        waveform              matlab.ui.control.UIAxes
        frequencySpectrum     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            [file,path] = uigetfile('*.wav','Select a .wav file'); % Prompts user to select a file for input and stores the filename and path as variables
            fullPath = append(path, file); % Converts the filename and path to a single string to be read by the audioread function
            % add file validation routine
            waveformY = audioread(fullPath); % Stores amplitude data in an array
            colororder(app.waveform, {'c', 'm'});
            app.waveform.Color = 'k';
            plot(app.waveform, waveformY); % Plots the data on the axes to display the waveform of the file
            %frequencySpectrumY = fft(waveformY);
            %plot(app.frequencySpectrum,frequencySpectrumY);
            app.waveform.Title.String = file;


            % change this
            [samples, sampleRate] = audioread(fullPath); % Obtains the samples and sample rate in Hz from the audio file imported
            bufferSize=256; % Sets the number of samples to be transformed via discrete fourier transform per iteration. A higher number means the transform will be taken from a larger time period so will be a more averaged representation of the sample, however a lower buffer size will give a more precise representation of the frequencies present at a given point in time of a signal which changes often and quickly
            % the buffer should be twice or three times as large as the
            % lowest frequency to be detected as this will give better
            % results for detecting said frequency using the fft function.
            % As the lowest frequency humans can hear is 20Hz, this value
            % will be used in the case of processing audio files.
            for i = 1:bufferSize/2+1
                Y = fft(samples, bufferSize+i); % Creates an array of amplitudes corrosponding to the discrete fourier transform of the imported file. This gives a spectrum of the frequencies present in the signal in the frequency domain with the Y axis corrosponding to their relative (convert to absolute in dB?) intensities
                f = sampleRate/2*linspace(0,1,bufferSize/2+1);
                plot(app.frequencySpectrum, f, abs(Y(1:bufferSize/2+1)));
                pause(1/sampleRate); % in order to plot in real time, using the formula f = 1/t, the delay time can be calculated by 1/f = t
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.frequencySpectrum = uiaxes(app.GridLayout);
            title(app.frequencySpectrum, 'Frequency Spectrum')
            xlabel(app.frequencySpectrum, 'Frequency')
            ylabel(app.frequencySpectrum, 'Amplitude')
            zlabel(app.frequencySpectrum, 'Time')
            app.waveform = uiaxes(app.GridLayout);
            title(app.waveform, '[ Import a file ]')
            xlabel(app.waveform, 'Time /s')
            ylabel(app.waveform, 'Amplitude /dB')
            zlabel(app.waveform, 'Z')
            app.ImportFileButton = uibutton(app.GridLayout, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.SignalProcessorLabel = uilabel(app.GridLayout);
            app.SignalProcessorLabel.Text = 'Signal Processor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalProcessor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end